classdef App_ayuda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AyudasunavariableUIFigure  matlab.ui.Figure
        AyudasparamtodosdeunavariableLabel  matlab.ui.control.Label
        MtodoDropDownLabel         matlab.ui.control.Label
        MtodoDropDown              matlab.ui.control.DropDown
        Button                     matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function actualizar_texto(app, value)
            switch value
                case "Incremental"
                    app.Button.Icon = "incremental.png";
                case "Secante"
                    app.Button.Icon = "secante.png";
                case "Newton"
                    app.Button.Icon = "newton.png";
                case "Regla Falsa"
                    app.Button.Icon = "reglafalsa.png";
                case "Bisección"
                    app.Button.Icon = "biseccion.png";
                case "Punto Fijo"
                    app.Button.Icon = "puntofijo.png";
                case "Raices Múltiples"
                    app.Button.Icon = "multiples.png";
                otherwise
                    app.Button.Icon = "unavar.png";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.MtodoDropDown.Value = metodo;
            actualizar_texto(app, metodo);
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            actualizar_texto(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AyudasunavariableUIFigure = uifigure('Visible', 'off');
            app.AyudasunavariableUIFigure.Name = 'Ayudas una variable';
            app.AyudasparamtodosdeunavariableLabel = uilabel(app.AyudasunavariableUIFigure);
            app.AyudasparamtodosdeunavariableLabel.Text = 'Ayudas para métodos de una variable';
            app.MtodoDropDownLabel = uilabel(app.AyudasunavariableUIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.AyudasunavariableUIFigure);
            app.MtodoDropDown.Items = {'Incremental', 'Secante', 'Newton', 'Regla Falsa', 'Bisección', 'Punto Fijo', 'Raices Múltiples'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Incremental';
            app.Button = uibutton(app.AyudasunavariableUIFigure, 'push');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.AyudasunavariableUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ayuda(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AyudasunavariableUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AyudasunavariableUIFigure)
        end
    end
end