classdef App_ayuda_interpolacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AyudainterpolacinUIFigure  matlab.ui.Figure
        Button                     matlab.ui.control.Button
        AyudasparamtodosdeinterpolacinLabel  matlab.ui.control.Label
        MtodoDropDownLabel         matlab.ui.control.Label
        MtodoDropDown              matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        
        function actualizar_texto(app, value)
            switch value
                case "Newton"
                    app.Button.Icon = "newton_dif.png";
                case "Lagrange"
                    app.Button.Icon = "lagrange.png";
                case "Trazadores"
                    app.Button.Icon = "splines.png";
                otherwise
                    app.Button.Icon = "interpolacion.png";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.MtodoDropDown.Value = metodo;
            actualizar_texto(app, metodo);
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            actualizar_texto(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AyudainterpolacinUIFigure = uifigure('Visible', 'off');
            app.AyudainterpolacinUIFigure.Name = 'Ayuda interpolación';
            app.Button = uibutton(app.AyudainterpolacinUIFigure, 'push');
            app.Button.Text = '';
            app.AyudasparamtodosdeinterpolacinLabel = uilabel(app.AyudainterpolacinUIFigure);
            app.AyudasparamtodosdeinterpolacinLabel.Text = 'Ayudas para métodos de interpolación';
            app.MtodoDropDownLabel = uilabel(app.AyudainterpolacinUIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.AyudainterpolacinUIFigure);
            app.MtodoDropDown.Items = {'Newton', 'Lagrange', 'Trazadores'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Newton';
            % Show the figure after all components are created
            app.AyudainterpolacinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ayuda_interpolacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AyudainterpolacinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AyudainterpolacinUIFigure)
        end
    end
end