classdef App_ayuda_sistemas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AyudasistemasdeecuacionesUIFigure  matlab.ui.Figure
        Button              matlab.ui.control.Button
        MtodoDropDownLabel  matlab.ui.control.Label
        MtodoDropDown       matlab.ui.control.DropDown
        AyudasparamtodossistemasdeecuacionesLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function actualizar_texto(app, value)
            switch value
                case "Gauss"
                    app.Button.Icon = "gauss.png";
                case "Pivoteo parcial"
                    app.Button.Icon = "parcial.png";
                case "Pivoteo escalado"
                    app.Button.Icon = "escalado.png";
                case "Pivoteo total"
                    app.Button.Icon = "total.png";
                case "Crout"
                    app.Button.Icon = "crout.png";
                case "Doolittle"
                    app.Button.Icon = "doolittle.png";
                case "Cholesky"
                    app.Button.Icon = "cholesky.png";
                case "Jacobi"
                    app.Button.Icon = "jacobi.png";
                case "Gauss seidel"
                    app.Button.Icon = "gauss_seidel.png";
                otherwise
                    app.Button.Icon = "sistemas.png";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.MtodoDropDown.Value = metodo;
            actualizar_texto(app, metodo);
        end

        % Value changed function: MtodoDropDown
        function MtodoDropDownValueChanged(app, event)
            value = app.MtodoDropDown.Value;
            actualizar_texto(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AyudasistemasdeecuacionesUIFigure = uifigure('Visible', 'off');
            app.AyudasistemasdeecuacionesUIFigure.Name = 'Ayuda sistemas de ecuaciones';
            app.Button = uibutton(app.AyudasistemasdeecuacionesUIFigure, 'push');
            app.Button.Text = '';
            app.MtodoDropDownLabel = uilabel(app.AyudasistemasdeecuacionesUIFigure);
            app.MtodoDropDownLabel.Text = 'Método';
            app.MtodoDropDown = uidropdown(app.AyudasistemasdeecuacionesUIFigure);
            app.MtodoDropDown.Items = {'--Eliminación--', 'Gauss', 'Pivoteo parcial', 'Pivoteo escalado', 'Pivoteo total', '--Factorización--', 'Crout', 'Doolittle', 'Cholesky', '--Iterativos--', 'Jacobi', 'Gauss seidel'};
            app.MtodoDropDown.ValueChangedFcn = createCallbackFcn(app, @MtodoDropDownValueChanged, true);
            app.MtodoDropDown.Value = 'Gauss';
            app.AyudasparamtodossistemasdeecuacionesLabel = uilabel(app.AyudasistemasdeecuacionesUIFigure);
            app.AyudasparamtodossistemasdeecuacionesLabel.Text = 'Ayudas para métodos sistemas de ecuaciones';
            % Show the figure after all components are created
            app.AyudasistemasdeecuacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_ayuda_sistemas(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AyudasistemasdeecuacionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AyudasistemasdeecuacionesUIFigure)
        end
    end
end