classdef App_eliminacion_steps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PasoseliminacionUIFigure  matlab.ui.Figure
        UITableA                  matlab.ui.control.Table
        AnteriorButton            matlab.ui.control.Button
        SiguienteButton           matlab.ui.control.Button
        Paso1Label                matlab.ui.control.Label
    end

    
    properties (Access = private)
        page = 1;
        pages = 4;
        matrices = cell(1,1);
    end
    
    methods (Access = private)
        
        
        function updateTable(app)
            app.UITableA.Data = app.matrices{app.page};
            app.Paso1Label.Text = "Paso "+num2str(app.page);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, matrices, paginas)
            app.matrices = matrices;
            app.pages = paginas;
            app.updateTable();
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            if app.page > 1
                app.page = app.page - 1;
                app.updateTable;
            else
                msgbox(sprintf("Primer paso"));
            end
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            if app.page < app.pages
                app.page = app.page + 1;
                app.updateTable;
            else
                msgbox(sprintf("Último paso"));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PasoseliminacionUIFigure = uifigure('Visible', 'off');
            app.PasoseliminacionUIFigure.Name = 'Pasos eliminacion';
            app.UITableA = uitable(app.PasoseliminacionUIFigure);
            app.UITableA.ColumnName = {''};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.AnteriorButton = uibutton(app.PasoseliminacionUIFigure, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.SiguienteButton = uibutton(app.PasoseliminacionUIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.Paso1Label = uilabel(app.PasoseliminacionUIFigure);
            app.Paso1Label.Text = 'Paso 1';
            % Show the figure after all components are created
            app.PasoseliminacionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_eliminacion_steps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PasoseliminacionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PasoseliminacionUIFigure)
        end
    end
end