classdef App_factorizacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FactorizacionUIFigure      matlab.ui.Figure
        UITableA                   matlab.ui.control.Table
        UITableB                   matlab.ui.control.Table
        UITableX                   matlab.ui.control.Table
        ALabel                     matlab.ui.control.Label
        BLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        UITableU                   matlab.ui.control.Table
        UITableL                   matlab.ui.control.Table
        LLabel                     matlab.ui.control.Label
        ULabel                     matlab.ui.control.Label
        CroutButton                matlab.ui.control.Button
        DoolittleButton            matlab.ui.control.Button
        CholeskyButton             matlab.ui.control.Button
        AyudaButton                matlab.ui.control.Button
        MtodosdeFactorizacinLabel  matlab.ui.control.Label
        VariablesEditFieldLabel    matlab.ui.control.Label
        VariablesEditField         matlab.ui.control.NumericEditField
        ActualizarButton           matlab.ui.control.Button
        MostrarpasosButton         matlab.ui.control.Button
        EnesperaLabel              matlab.ui.control.Label
        ResolverButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        matrizL = cell(1,1);
        matrizU = cell(1,1);
        matrizFact = false;
        pasos = 4;
    end
    
    methods (Access = private)
        
        function tamano_tabla(app)
            tam = app.VariablesEditField.Value;
            t = zeros(tam,tam);
            t2 = zeros(tam,1);
            app.UITableA.Data = t;
            app.UITableB.Data = t2;
        end
        
        function [L,U] = Cholesky(app)
            A = app.UITableA.Data;
            n = app.VariablesEditField.Value;
            L = eye(n);
            U = eye(n);
            matL = cell(1,n);
            matU = cell(1,n);
            matL{1} = L;
            matU{1} = U;
            for k = 1:n
                sum = 0;
                for s = 1:(k-1)
                    sum = sum + L(k,s)*U(s,k);
                end
                
                tempR = sqrt(A(k,k) - sum);
                if isreal(tempR)
                    L(k,k) = tempR;
                else
                    msgbox("Imposible factorizar.");
                    return
                end
        		U(k,k) = L(k,k);
                for j = (k+1):n
                    sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(k,s)*U(s,j);
                    end
                    tempA = (A(k,j) - sum);
                    tempB = L(k,k);
                    if tempB ~= 0
                        U(k,j) = tempA/tempB;
                    else
                        msgbox("Imposible factorizar.");
                        return
                    end
                end
                for i = (k+1):n
        			sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(i,s)*U(s,k);
                    end
                    tempA = (A(i,k) - sum);
                    tempB = L(k,k);
                    if tempB ~= 0
                        L(i,k) = tempA/tempB;
                    else
                        msgbox("Imposible factorizar.");
                        return
                    end
        			
                end
                matL{k+1} = L;
                matU{k+1} = U;
            end
            app.matrizFact = true;
            app.matrizL = matL;
            app.matrizU = matU;
            app.pasos = n;
            app.UITableU.Data = U;
            app.UITableL.Data = L;
            app.EnesperaLabel.Text = "Factorizado";
        end
     
        
        function [L,U] = Doolittle(app)
            A = app.UITableA.Data;
            n = app.VariablesEditField.Value;
            U = eye(n);
        	L = eye(n);
            matL = cell(1,n);
            matU = cell(1,n);
            matL{1} = L;
            matU{1} = U;
            for k = 1:n
                for j = k:n
        			sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(k,s)*U(s,j);
                    end
        			U(k,j) = A(k,j) - sum;
                end
                for i = (k+1):n
        			sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(i,s)*U(s,k);
                    end
                    tempA = (A(i,k) - sum);
                    tempB = U(k,k);
                    if tempB ~= 0
                        L(i,k) = tempA/tempB;
                    else
                        msgbox("Imposible factorizar.");
                        return
                    end
                end
                matL{k+1} = L;
                matU{k+1} = U;
            end
            app.matrizFact = true;
            app.matrizL = matL;
            app.matrizU = matU;
            app.pasos = n;
        	app.UITableU.Data = U;
            app.UITableL.Data = L;
            app.EnesperaLabel.Text = "Factorizado";
        end
        
        function [L,U] = Crout(app)
            A = app.UITableA.Data;
            n = app.VariablesEditField.Value;
            U = eye(n);
        	L = eye(n);
            matL = cell(1,n);
            matU = cell(1,n);
            matL{1} = L;
            matU{1} = U;
            for k =  1:n
                for i = k:n
        			sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(i,s)*U(s,k);
                    end
        			L(i,k) = A(i,k) - sum;
                end
                for j = (k+1):n
        			sum = 0;
                    for s = 1:(k-1)
        				sum = sum + L(k,s)*U(s,j);
                    end
                    tempA = (A(k,j) - sum);
                    tempB = L(k,k);
                    if tempB ~= 0
                        U(k,j) = tempA/tempB;
                    else
                        msgbox("Imposible factorizar.");
                        return
                    end
                end
                matL{k+1} = L;
                matU{k+1} = U;
            end
            app.matrizFact = true;
            app.matrizL = matL;
            app.matrizU = matU;
            app.pasos = n;
        	app.UITableU.Data = U;
            app.UITableL.Data = L;
            app.EnesperaLabel.Text = "Factorizado";
        end
        
        function x = Sust_regresiva(app,A,b,n)
            x = zeros(n,1);
            x(n) = b(n)/A(n,n);               
            for i = n-1:-1:1                    
                sum = 0;
                for j = n:-1:i+1                
                    sum = sum + A(i,j)*x(j);    
                end 
                x(i) = (b(i)- sum)/A(i,i);
            end 
            app.UITableX.Data = x;
        end
        
        function x = Sust_progresiva(app,Ab,n)
            x = zeros(n,1);
            x(1) = Ab(1,n+1)/Ab(1,1);
            for i = 2:n
                s = 0;
                for j = 1:(i-1)
                    s = s + Ab(i,j)*x(j);
                end
                x(i) = (Ab(i,n+1)-s)/Ab(i,i);
            end
            app.UITableX.Data = x;
        end
        
        function checkDiagonal(~,A)
            for i = 1:size(A,1)
                if A(i,i) == 0
                    msgbox("Error en la diagonal.");
                    return
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, A, b, vars)
            app.UITableA.Data = A;
            app.UITableB.Data = b;
            app.VariablesEditField.Value = vars;
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.tamano_tabla();
        end

        % Button pushed function: CholeskyButton
        function CholeskyButtonPushed(app, event)
            [L,U] = app.Cholesky();
            app.checkDiagonal(L);
            app.checkDiagonal(U);
        end

        % Button pushed function: CroutButton
        function CroutButtonPushed(app, event)
            [L,U] = app.Crout();
            app.checkDiagonal(L);
            app.checkDiagonal(U);
        end

        % Button pushed function: DoolittleButton
        function DoolittleButtonPushed(app, event)
            [L,U] = app.Doolittle();
            app.checkDiagonal(L);
            app.checkDiagonal(U);
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            if app.matrizFact
                L = app.UITableL.Data;
                U = app.UITableU.Data;
                b = app.UITableB.Data;
                n = app.VariablesEditField.Value;
            	Lb = [L b];
            	z = app.Sust_progresiva(Lb,n);
            	x = app.Sust_regresiva(U,z,n);
                app.UITableX.Data = x;
                app.EnesperaLabel.Text = "Resuelto";
            else
                msgbox("La matriz debe ser factorizada");
            end
        end

        % Button pushed function: MostrarpasosButton
        function MostrarpasosButtonPushed(app, event)
            App_factorizacion_steps(app.matrizL,app.matrizU,app.pasos);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_sistemas("Crout");
        end

        % Close request function: FactorizacionUIFigure
        function FactorizacionUIFigureCloseRequest(app, event)
            setappdata(0,'A',app.UITableA.Data);
            setappdata(0,'b',app.UITableB.Data);
            setappdata(0,'vars',app.VariablesEditField.Value);
            delete(app);
        end

        % Cell edit callback: UITableA
        function UITableACellEdit(app, event)
            app.EnesperaLabel.Text = "Matriz actualizada";
            app.matrizFact = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FactorizacionUIFigure = uifigure('Visible', 'off');
            app.FactorizacionUIFigure.Name = 'Factorizacion';
            app.FactorizacionUIFigure.CloseRequestFcn = createCallbackFcn(app, @FactorizacionUIFigureCloseRequest, true);
            app.UITableA = uitable(app.FactorizacionUIFigure);
            app.UITableA.ColumnName = {''};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableA.CellEditCallback = createCallbackFcn(app, @UITableACellEdit, true);
            app.UITableB = uitable(app.FactorizacionUIFigure);
            app.UITableB.ColumnName = {''};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.UITableX = uitable(app.FactorizacionUIFigure);
            app.UITableX.ColumnName = {''};
            app.UITableX.RowName = {};
            app.UITableX.ColumnEditable = true;
            app.ALabel = uilabel(app.FactorizacionUIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.FactorizacionUIFigure);
            app.BLabel.Text = 'B';
            app.XLabel = uilabel(app.FactorizacionUIFigure);
            app.XLabel.Text = 'X';
            app.Label = uilabel(app.FactorizacionUIFigure);
            app.Label.Text = '=';
            app.UITableU = uitable(app.FactorizacionUIFigure);
            app.UITableU.ColumnName = {''};
            app.UITableU.RowName = {};
            app.UITableU.ColumnEditable = true;
            app.UITableL = uitable(app.FactorizacionUIFigure);
            app.UITableL.ColumnName = {''};
            app.UITableL.RowName = {};
            app.UITableL.ColumnEditable = true;
            app.LLabel = uilabel(app.FactorizacionUIFigure);
            app.LLabel.Text = 'L';
            app.ULabel = uilabel(app.FactorizacionUIFigure);
            app.ULabel.Text = 'U';
            app.CroutButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.CroutButton.ButtonPushedFcn = createCallbackFcn(app, @CroutButtonPushed, true);
            app.CroutButton.Text = 'Crout';
            app.DoolittleButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.DoolittleButton.ButtonPushedFcn = createCallbackFcn(app, @DoolittleButtonPushed, true);
            app.DoolittleButton.Text = 'Doolittle';
            app.CholeskyButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.CholeskyButton.ButtonPushedFcn = createCallbackFcn(app, @CholeskyButtonPushed, true);
            app.CholeskyButton.Text = 'Cholesky';
            app.AyudaButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.MtodosdeFactorizacinLabel = uilabel(app.FactorizacionUIFigure);
            app.MtodosdeFactorizacinLabel.Text = 'Métodos de Factorización';
            app.VariablesEditFieldLabel = uilabel(app.FactorizacionUIFigure);
            app.VariablesEditFieldLabel.Text = 'Variables';
            app.VariablesEditField = uieditfield(app.FactorizacionUIFigure, 'numeric');
            app.VariablesEditField.Value = 4;
            app.ActualizarButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.MostrarpasosButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.MostrarpasosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarpasosButtonPushed, true);
            app.MostrarpasosButton.Text = 'Mostrar pasos';
            app.EnesperaLabel = uilabel(app.FactorizacionUIFigure);
            app.EnesperaLabel.Text = 'En espera';
            app.ResolverButton = uibutton(app.FactorizacionUIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            % Show the figure after all components are created
            app.FactorizacionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_factorizacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FactorizacionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FactorizacionUIFigure)
        end
    end
end