classdef App_factorizacion_steps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PasosfactorizacionUIFigure  matlab.ui.Figure
        UITableL                    matlab.ui.control.Table
        UITableU                    matlab.ui.control.Table
        AnteriorButton              matlab.ui.control.Button
        SiguienteButton             matlab.ui.control.Button
        LLabel                      matlab.ui.control.Label
        ULabel                      matlab.ui.control.Label
        Paso1Label                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        L = cell(1,1);
        U = cell(1,1);
        page = 1;
        pages = 4;
    end
    
    methods (Access = private)
        
        function updateTable(app)
            app.UITableL.Data = app.L{app.page};
            app.UITableU.Data = app.U{app.page};
            app.Paso1Label.Text = "Paso "+num2str(app.page);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, L, U, paginas)
            app.L = L;
            app.U = U;
            app.pages = paginas;
            app.updateTable();
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            if app.page > 1
                app.page = app.page - 1;
                app.updateTable;
            else
                msgbox(sprintf("Primer paso"));
            end
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
            if app.page <= app.pages
                app.page = app.page + 1;
                app.updateTable;
            else
                msgbox(sprintf("Último paso"));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PasosfactorizacionUIFigure = uifigure('Visible', 'off');
            app.PasosfactorizacionUIFigure.Name = 'Pasos factorizacion';
            app.UITableL = uitable(app.PasosfactorizacionUIFigure);
            app.UITableL.ColumnName = {''};
            app.UITableL.RowName = {};
            app.UITableL.ColumnEditable = true;
            app.UITableU = uitable(app.PasosfactorizacionUIFigure);
            app.UITableU.ColumnName = {''};
            app.UITableU.RowName = {};
            app.UITableU.ColumnEditable = true;
            app.AnteriorButton = uibutton(app.PasosfactorizacionUIFigure, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.SiguienteButton = uibutton(app.PasosfactorizacionUIFigure, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.LLabel = uilabel(app.PasosfactorizacionUIFigure);
            app.LLabel.Text = 'L';
            app.ULabel = uilabel(app.PasosfactorizacionUIFigure);
            app.ULabel.Text = 'U';
            app.Paso1Label = uilabel(app.PasosfactorizacionUIFigure);
            app.Paso1Label.Text = 'Paso 1';
            % Show the figure after all components are created
            app.PasosfactorizacionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_factorizacion_steps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PasosfactorizacionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PasosfactorizacionUIFigure)
        end
    end
end