classdef App_grapher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraficadorUIFigure        matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        LimpiarButton             matlab.ui.control.Button
        IngresarfxEditFieldLabel  matlab.ui.control.Label
        IngresarfxEditField       matlab.ui.control.EditField
        AgregarButton             matlab.ui.control.Button
        GraficadorLabel           matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func)
            hold(app.UIAxes, 'on')
            app.IngresarfxEditField.Value = func;
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            funcs = app.UITable.Data;
            func = app.IngresarfxEditField.Value;
            try
                fplot(app.UIAxes,func);
                newdata = [funcs;{func,true}];
                app.UITable.Data = newdata;
                app.IngresarfxEditField.Value = "";
            catch
                msgbox("Error en la función, revísela y vuelva a intentar.");
            end
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes);
            app.UITable.Data = [];
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            cla(app.UIAxes);
            Matrix = app.UITable.Data;
            for i = 1:size(Matrix,1)
                func = Matrix{i,2};
                disp(func)
                if  func == true
                    fplot(app.UIAxes,Matrix{i,1});
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraficadorUIFigure = uifigure('Visible', 'off');
            app.GraficadorUIFigure.Name = 'Graficador';
            app.UIAxes = uiaxes(app.GraficadorUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LimpiarButton = uibutton(app.GraficadorUIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.IngresarfxEditFieldLabel = uilabel(app.GraficadorUIFigure);
            app.IngresarfxEditFieldLabel.Text = 'Ingresar f(x)';
            app.IngresarfxEditField = uieditfield(app.GraficadorUIFigure, 'text');
            app.AgregarButton = uibutton(app.GraficadorUIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.GraficadorLabel = uilabel(app.GraficadorUIFigure);
            app.GraficadorLabel.Text = 'Graficador';
            app.UITable = uitable(app.GraficadorUIFigure);
            app.UITable.ColumnName = {'Funciones'; 'Visible'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.GraficadorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_grapher(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraficadorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraficadorUIFigure)
        end
    end
end