classdef App_interpolacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterpolacinUIFigure       matlab.ui.Figure
        AnlisisNumricoLabel        matlab.ui.control.Label
        MtodosdeinterpolacinLabel  matlab.ui.control.Label
        PolinomiosButton           matlab.ui.control.Button
        TrazadoresButton           matlab.ui.control.Button
        VolverButton               matlab.ui.control.Button
        GraficadorButton           matlab.ui.control.Button
        AyudaButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        numPuntos = 4;
        puntos = zeros(4,2);
    end
    
    methods (Access = private)
        
        function update_values(app)
            app.puntos = getappdata(0,'puntos');
            app.numPuntos = getappdata(0,'numPuntos');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PolinomiosButton
        function PolinomiosButtonPushed(app, event)
            app.update_values();
            App_polinomios(app.puntos, app.numPuntos);
        end

        % Button pushed function: TrazadoresButton
        function TrazadoresButtonPushed(app, event)
            app.update_values();
            App_splines(app.puntos,app.numPuntos);
        end

        % Button pushed function: GraficadorButton
        function GraficadorButtonPushed(app, event)
            App_grapher(" ");
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            App_Main();
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_interpolacion("Newton");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InterpolacinUIFigure = uifigure('Visible', 'off');
            app.InterpolacinUIFigure.Name = 'Interpolación';
            app.AnlisisNumricoLabel = uilabel(app.InterpolacinUIFigure);
            app.AnlisisNumricoLabel.Text = 'Análisis Numérico';
            app.MtodosdeinterpolacinLabel = uilabel(app.InterpolacinUIFigure);
            app.MtodosdeinterpolacinLabel.Text = 'Métodos de interpolación';
            app.PolinomiosButton = uibutton(app.InterpolacinUIFigure, 'push');
            app.PolinomiosButton.ButtonPushedFcn = createCallbackFcn(app, @PolinomiosButtonPushed, true);
            app.PolinomiosButton.Text = 'Polinomios';
            app.TrazadoresButton = uibutton(app.InterpolacinUIFigure, 'push');
            app.TrazadoresButton.ButtonPushedFcn = createCallbackFcn(app, @TrazadoresButtonPushed, true);
            app.TrazadoresButton.Text = 'Trazadores';
            app.VolverButton = uibutton(app.InterpolacinUIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.GraficadorButton = uibutton(app.InterpolacinUIFigure, 'push');
            app.GraficadorButton.ButtonPushedFcn = createCallbackFcn(app, @GraficadorButtonPushed, true);
            app.GraficadorButton.Text = 'Graficador';
            app.AyudaButton = uibutton(app.InterpolacinUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.InterpolacinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_interpolacion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterpolacinUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterpolacinUIFigure)
        end
    end
end