classdef App_iterativos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IterativosUIFigure         matlab.ui.Figure
        VariablesEditFieldLabel    matlab.ui.control.Label
        VariablesEditField         matlab.ui.control.NumericEditField
        ActualizarButton           matlab.ui.control.Button
        UITableA                   matlab.ui.control.Table
        UITableB                   matlab.ui.control.Table
        UITableX1                  matlab.ui.control.Table
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        LambdaEditFieldLabel       matlab.ui.control.Label
        LambdaEditField            matlab.ui.control.NumericEditField
        GaussseidelButton          matlab.ui.control.Button
        JacobiButton               matlab.ui.control.Button
        UITableI                   matlab.ui.control.Table
        ResultadosLabel            matlab.ui.control.Label
        ALabel                     matlab.ui.control.Label
        BLabel                     matlab.ui.control.Label
        XLabel                     matlab.ui.control.Label
        AyudaButton                matlab.ui.control.Button
        MtodosIterativosLabel      matlab.ui.control.Label
        UITableX0                  matlab.ui.control.Table
        XLabel_2                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function tamano_tabla(app)
            tam = app.VariablesEditField.Value;
            t = zeros(tam,tam);
            t2 = zeros(tam,1);
            app.UITableA.Data = t;
            app.UITableB.Data = t2;
            app.UITableX0.Data = t2;
        end
        
        
        function iteraciones(app,modo)
            A = app.UITableA.Data;
            b = app.UITableB.Data;
            n = app.VariablesEditField.Value;
            x0 = app.UITableX0.Data';
            tol = app.ToleranciaEditField.Value;
            iter = app.IteracionesEditField.Value;
            lambda = app.LambdaEditField.Value;
            C = 0;
            if tol < 0
                msgbox(sprintf("El valor de la tolerancia es inadecuado"));
            elseif iter <= 0
                msgbox(sprintf("El valor de las iteraciones es inadecuado"));
            else
                E = tol +1;
                x1 = x0;
                resultado = [[x0,0];]; %#ok<*NBRAK> 
                while E>tol && C<iter
                    for i = 1:n
                        sum = 0;
                        for j = 1:n
                            if j~=i
                                if modo == 1
                                    sum = sum + A(i,j)*x1(j);
                                elseif modo == 0
                                    sum = sum + A(i,j)*x0(j);
                                end
                            end
                        end
                        tempA = lambda*(b(i)-sum);
                        tempB = A(i,i);
                        if tempB ~= 0
                             x1(i) = tempA/tempB+(1-lambda)*x0(i);
                        else
                            msgbox("Imposible resolver");
                            return
                        end
                    end
                    tempA = norm(x1-x0);
                    tempB = norm(x1);
                    if tempB ~= 0
                        E = abs(tempA/tempB);
                    else
                        msgbox("Imposible resolver");
                        return
                    end
                    x0 = x1;
                    C = C+1;
                    resultado = [resultado;[x0,E];]; %#ok<*AGROW> 
                end
                if E<tol
                    app.UITableX1.Data = arrayfun(@ (n) sprintf("%1.15f", n),x1');
                else
                    msgbox(sprintf("Fracasó en el número de iteraciones."));
                end
                names = ["n",1:n,"Error"];
                app.UITableI.ColumnName = names;
                datosFormato = arrayfun(@ (n) sprintf("%1.15f", n),resultado);
                iteraciones = (0:C)';
                app.UITableI.Data = [iteraciones datosFormato];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, A, b, vars)
            format bank;
            app.UITableA.Data = A;
            app.UITableB.Data = b;
            app.UITableX0.Data = zeros(vars,1);
            app.VariablesEditField.Value = vars;
        end

        % Button pushed function: GaussseidelButton
        function GaussseidelButtonPushed(app, event)
            app.iteraciones(1)
        end

        % Button pushed function: JacobiButton
        function JacobiButtonPushed(app, event)
            app.iteraciones(0);
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.tamano_tabla();
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_sistemas("Jacobi");
        end

        % Close request function: IterativosUIFigure
        function IterativosUIFigureCloseRequest(app, event)
            setappdata(0,'A',app.UITableA.Data);
            setappdata(0,'b',app.UITableB.Data);
            setappdata(0,'vars',app.VariablesEditField.Value);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IterativosUIFigure = uifigure('Visible', 'off');
            app.IterativosUIFigure.Name = 'Iterativos';
            app.IterativosUIFigure.CloseRequestFcn = createCallbackFcn(app, @IterativosUIFigureCloseRequest, true);
            app.VariablesEditFieldLabel = uilabel(app.IterativosUIFigure);
            app.VariablesEditFieldLabel.Text = 'Variables';
            app.VariablesEditField = uieditfield(app.IterativosUIFigure, 'numeric');
            app.VariablesEditField.Value = 4;
            app.ActualizarButton = uibutton(app.IterativosUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.UITableA = uitable(app.IterativosUIFigure);
            app.UITableA.ColumnName = {''};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.UITableB = uitable(app.IterativosUIFigure);
            app.UITableB.ColumnName = {''};
            app.UITableB.RowName = {};
            app.UITableB.ColumnEditable = true;
            app.UITableX1 = uitable(app.IterativosUIFigure);
            app.UITableX1.ColumnName = {''};
            app.UITableX1.RowName = {};
            app.UITableX1.ColumnEditable = true;
            app.ToleranciaEditFieldLabel = uilabel(app.IterativosUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.IterativosUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.IterativosUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.IterativosUIFigure, 'numeric');
            app.LambdaEditFieldLabel = uilabel(app.IterativosUIFigure);
            app.LambdaEditFieldLabel.Text = 'Lambda';
            app.LambdaEditField = uieditfield(app.IterativosUIFigure, 'numeric');
            app.LambdaEditField.Value = 1;
            app.GaussseidelButton = uibutton(app.IterativosUIFigure, 'push');
            app.GaussseidelButton.ButtonPushedFcn = createCallbackFcn(app, @GaussseidelButtonPushed, true);
            app.GaussseidelButton.Text = 'Gauss seidel';
            app.JacobiButton = uibutton(app.IterativosUIFigure, 'push');
            app.JacobiButton.ButtonPushedFcn = createCallbackFcn(app, @JacobiButtonPushed, true);
            app.JacobiButton.Text = 'Jacobi';
            app.UITableI = uitable(app.IterativosUIFigure);
            app.UITableI.ColumnName = {''};
            app.UITableI.RowName = {};
            app.UITableI.ColumnEditable = true;
            app.ResultadosLabel = uilabel(app.IterativosUIFigure);
            app.ResultadosLabel.Text = 'Resultados:';
            app.ALabel = uilabel(app.IterativosUIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.IterativosUIFigure);
            app.BLabel.Text = 'B';
            app.XLabel = uilabel(app.IterativosUIFigure);
            app.XLabel.Text = 'X';
            app.AyudaButton = uibutton(app.IterativosUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.MtodosIterativosLabel = uilabel(app.IterativosUIFigure);
            app.MtodosIterativosLabel.Text = 'Métodos Iterativos';
            app.UITableX0 = uitable(app.IterativosUIFigure);
            app.UITableX0.ColumnName = {''};
            app.UITableX0.RowName = {};
            app.UITableX0.ColumnEditable = true;
            app.XLabel_2 = uilabel(app.IterativosUIFigure);
            app.XLabel_2.Text = 'X';
            % Show the figure after all components are created
            app.IterativosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_iterativos(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IterativosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IterativosUIFigure)
        end
    end
end