classdef App_polinomios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewtonyLagrangeUIFigure         matlab.ui.Figure
        UITableA                        matlab.ui.control.Table
        NPuntosEditFieldLabel           matlab.ui.control.Label
        NPuntosEditField                matlab.ui.control.NumericEditField
        ActualizarButton                matlab.ui.control.Button
        NewtonButton                    matlab.ui.control.Button
        LagrangeButton                  matlab.ui.control.Button
        EcuacinresultadoEditFieldLabel  matlab.ui.control.Label
        EcuacinresultadoEditField       matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        MtodosdeNewtonyLagrangeLabel    matlab.ui.control.Label
        AyudaButton                     matlab.ui.control.Button
        AcumularCheckBox                matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function tamano_tabla(app)
            tam = app.NPuntosEditField.Value;
            t = zeros(tam,2);
            app.UITableA.Data = t;
        end
        
        function exp = lagrange(~,x,y,n)
        	p = 0;
            for k = 1:n
        		Lk = "1";
                for i = 1:n
                    if i ~= k
        				Lk = Lk +"*((x-"+ num2str(x(i))+")/("+num2str(x(k)-x(i))+"))";
                    end
                end
        		p = p + " + (("+Lk+")*"+num2str(y(k))+")";
            end
            exp = p;
        end
        
        function exp = newton(~,x,y,n)
            format long g
            %calculo de valores b
            f0 = y;
            f1 = [];
            for k = 2:n
                for i = 1:(n-k+1)
                    f1(i) = (f0(i+1)-f0(i))/(x(i+k-1)-x(i));
                end
                b(k) = f1(1); %#ok<*AGROW> 
                f0 = f1;
            end
            %construcción de la expresión
            c = 0;
            pol = num2str(y(1));
            for i = 1:n
                bi = b(i);
                sec = "";
                if bi ~= 0
                    sec = "+" + num2str(b(i));
                    for j = 1:c
                        sec = sec + "*(x-" +  num2str(x(j)) + ")";
                    end
                else
                end
                c = c+1;
                pol = pol + sec;
            end
            exp = pol;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, puntos, numPuntos)
           app.UITableA.Data = puntos;
           app.NPuntosEditField.Value = numPuntos;
        end

        % Button pushed function: NewtonButton
        function NewtonButtonPushed(app, event)
            x = app.UITableA.Data(:,1);
            y = app.UITableA.Data(:,end);
            n = app.NPuntosEditField.Value;
            if length(x) == length(unique(x))
                ecu = string(app.newton(x',y',n));
                app.EcuacinresultadoEditField.Value = ecu;
                if app.AcumularCheckBox.Value == 1
                    hold(app.UIAxes,'on');
                else
                    hold(app.UIAxes,'off');
                end
                plot(app.UIAxes,x', y', 'o');
                hold(app.UIAxes,'on');
                fplot(app.UIAxes,ecu);
            else
                msgbox("Existen dos puntos con un mismo valor de x.");
            end
            
        end

        % Button pushed function: LagrangeButton
        function LagrangeButtonPushed(app, event)
            x = app.UITableA.Data(:,1);
            y = app.UITableA.Data(:,end);
            n = app.NPuntosEditField.Value;
            if length(x) == length(unique(x))
                ecu = string(app.lagrange(x',y',n));
                app.EcuacinresultadoEditField.Value = ecu;
                if app.AcumularCheckBox.Value == 1
                    hold(app.UIAxes,'on');
                else
                    hold(app.UIAxes,'off');
                end
                plot(app.UIAxes,x', y', 'o');
                hold(app.UIAxes,'on');
                fplot(app.UIAxes,ecu);
            else
                msgbox("Existen dos puntos con un mismo valor de x.");
            end
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.tamano_tabla();
        end

        % Close request function: NewtonyLagrangeUIFigure
        function NewtonyLagrangeUIFigureCloseRequest(app, event)
            setappdata(0,'puntos',app.UITableA.Data);
            setappdata(0,'numPuntos',app.NPuntosEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_interpolacion("Newton");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewtonyLagrangeUIFigure = uifigure('Visible', 'off');
            app.NewtonyLagrangeUIFigure.Name = 'Newton y Lagrange';
            app.NewtonyLagrangeUIFigure.CloseRequestFcn = createCallbackFcn(app, @NewtonyLagrangeUIFigureCloseRequest, true);
            app.UITableA = uitable(app.NewtonyLagrangeUIFigure);
            app.UITableA.ColumnName = {'X'; 'Y'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.NPuntosEditFieldLabel = uilabel(app.NewtonyLagrangeUIFigure);
            app.NPuntosEditFieldLabel.Text = 'N. Puntos';
            app.NPuntosEditField = uieditfield(app.NewtonyLagrangeUIFigure, 'numeric');
            app.NPuntosEditField.Value = 4;
            app.ActualizarButton = uibutton(app.NewtonyLagrangeUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.NewtonButton = uibutton(app.NewtonyLagrangeUIFigure, 'push');
            app.NewtonButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonButtonPushed, true);
            app.NewtonButton.Text = 'Newton';
            app.LagrangeButton = uibutton(app.NewtonyLagrangeUIFigure, 'push');
            app.LagrangeButton.ButtonPushedFcn = createCallbackFcn(app, @LagrangeButtonPushed, true);
            app.LagrangeButton.Text = 'Lagrange';
            app.EcuacinresultadoEditFieldLabel = uilabel(app.NewtonyLagrangeUIFigure);
            app.EcuacinresultadoEditFieldLabel.Text = 'Ecuación resultado';
            app.EcuacinresultadoEditField = uieditfield(app.NewtonyLagrangeUIFigure, 'text');
            app.EcuacinresultadoEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.NewtonyLagrangeUIFigure);
            title(app.UIAxes, 'Resultados')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MtodosdeNewtonyLagrangeLabel = uilabel(app.NewtonyLagrangeUIFigure);
            app.MtodosdeNewtonyLagrangeLabel.Text = 'Métodos de Newton y Lagrange';
            app.AyudaButton = uibutton(app.NewtonyLagrangeUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.AcumularCheckBox = uicheckbox(app.NewtonyLagrangeUIFigure);
            app.AcumularCheckBox.Text = 'Acumular';
            % Show the figure after all components are created
            app.NewtonyLagrangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_polinomios(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewtonyLagrangeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewtonyLagrangeUIFigure)
        end
    end
end