classdef App_puntofijo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PuntofijoUIFigure          matlab.ui.Figure
        X0EditFieldLabel           matlab.ui.control.Label
        X0EditField                matlab.ui.control.NumericEditField
        MTODODEPUNTOFIJOLabel      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        UITable                    matlab.ui.control.Table
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        ErrorSwitchLabel           matlab.ui.control.Label
        ErrorSwitch                matlab.ui.control.ToggleSwitch
        FuncinfEditFieldLabel      matlab.ui.control.Label
        FuncinfEditField           matlab.ui.control.EditField
        FuncingEditFieldLabel      matlab.ui.control.Label
        FuncingEditField           matlab.ui.control.EditField
        EjecutarButton             matlab.ui.control.Button
        AyudaButton                matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function [resultado] = puntofijo_metodo(app,func,gunc,x0,tol,iter,err)
            resultado = [];
            f = inline(func);
            g = inline(gunc);
            y0 = f(x0);
            cont = 1;
            error = tol + 1;
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            if tol <= 0
                msgbox(sprintf('Ingrese una tolerancia valida'));
            else
                if iter <= 1
                    
                    msgbox(sprintf('Ingrese un numero de iteraciones valido'));
                else
                    if y0 == 0
                        msgbox(sprintf('%g es una raiz',x0));
                    else
                        resultado = [[0,x0,y0,"---"];];
                        while y0 ~= 0 && isreal(y0) && error > tol && cont < iter
                            xn = g(x0);
                            error = er(xn,x0);
                            y0 = f(xn);
                            x0 = xn;
                            resultado = [resultado;{cont,num2str(x0,15),num2str(y0,15),error};];
                            cont = cont + 1;
                        end
            
                        if y0 == 0 
                            msgbox(sprintf('%g es una raiz.', x0));
                        elseif error < tol
                            msgbox(sprintf('%g es una raiz aproximada.', x0));
                        else
                            msgbox(sprintf('Fracaso en %g iteraciones.',iter));
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func, x0, tol, iter)
            app.FuncinfEditField.Value = func;
            app.X0EditField.Value = x0;
            app.ToleranciaEditField.Value = tol;
            app.IteracionesEditField.Value = iter;
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinfEditField.Value;
            gunc = app.FuncingEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion f."));
            else
                try
                    fplot(app.UIAxes,func);
                catch
                    msgbox("Error en la función, revísela y vuelva a intentar.");
                end
                if gunc == ""
                    msgbox(sprintf("Ingrese una funcion g."));
                else
                    x0 = app.X0EditField.Value;
                    tol = app.ToleranciaEditField.Value;
                    iter = app.IteracionesEditField.Value;
                    err = app.ErrorSwitch.Value;
                    
                    t = puntofijo_metodo(app,func,gunc,x0,tol,iter,err);
                    app.UITable.Data = t;
                end
            end
        end

        % Close request function: PuntofijoUIFigure
        function PuntofijoUIFigureCloseRequest(app, event)
            setappdata(0,'f',app.FuncinfEditField.Value);
            setappdata(0,'x0',app.X0EditField.Value);
            setappdata(0,'t',app.ToleranciaEditField.Value);
            setappdata(0,'i',app.IteracionesEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda("Punto Fijo");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PuntofijoUIFigure = uifigure('Visible', 'off');
            app.PuntofijoUIFigure.Name = 'Punto fijo';
            app.PuntofijoUIFigure.CloseRequestFcn = createCallbackFcn(app, @PuntofijoUIFigureCloseRequest, true);
            app.X0EditFieldLabel = uilabel(app.PuntofijoUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.PuntofijoUIFigure, 'numeric');
            app.MTODODEPUNTOFIJOLabel = uilabel(app.PuntofijoUIFigure);
            app.MTODODEPUNTOFIJOLabel.Text = 'MÉTODO DE PUNTO FIJO';
            app.UIAxes = uiaxes(app.PuntofijoUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.PuntofijoUIFigure);
            app.UITable.ColumnName = {'n'; 'xn'; 'f(xn)'; 'Error'};
            app.UITable.RowName = {};
            app.ToleranciaEditFieldLabel = uilabel(app.PuntofijoUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.PuntofijoUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.PuntofijoUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.PuntofijoUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.PuntofijoUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.PuntofijoUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Absoluto', 'Relativo'};
            app.ErrorSwitch.Value = 'Relativo';
            app.FuncinfEditFieldLabel = uilabel(app.PuntofijoUIFigure);
            app.FuncinfEditFieldLabel.Text = 'Función f';
            app.FuncinfEditField = uieditfield(app.PuntofijoUIFigure, 'text');
            app.FuncingEditFieldLabel = uilabel(app.PuntofijoUIFigure);
            app.FuncingEditFieldLabel.Text = 'Función g';
            app.FuncingEditField = uieditfield(app.PuntofijoUIFigure, 'text');
            app.EjecutarButton = uibutton(app.PuntofijoUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.AyudaButton = uibutton(app.PuntofijoUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.PuntofijoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_puntofijo(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PuntofijoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PuntofijoUIFigure)
        end
    end
end