classdef App_raicesmultiples < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RaicesmultiplesUIFigure        matlab.ui.Figure
        FuncinEditFieldLabel           matlab.ui.control.Label
        FuncinEditField                matlab.ui.control.EditField
        PrimeraderivadaEditFieldLabel  matlab.ui.control.Label
        PrimeraderivadaEditField       matlab.ui.control.EditField
        SegundaderivadaEditFieldLabel  matlab.ui.control.Label
        SegundaderivadaEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        MTODODERAICESMLTIPLESLabel     matlab.ui.control.Label
        ToleranciaEditFieldLabel       matlab.ui.control.Label
        ToleranciaEditField            matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel      matlab.ui.control.Label
        IteracionesEditField           matlab.ui.control.NumericEditField
        ErrorSwitchLabel               matlab.ui.control.Label
        ErrorSwitch                    matlab.ui.control.ToggleSwitch
        EjecutarButton                 matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        X0EditFieldLabel               matlab.ui.control.Label
        X0EditField                    matlab.ui.control.NumericEditField
        AyudaButton                    matlab.ui.control.Button
    end

    methods (Access = public)
        
        function [resultado] = raicesmultiples_metodo(app,func,x0,tol,iter,err)
            resultado = [];
            f = inline(func); %#ok<*DINLN> 
            dp = symbderiv(func,'x');
            d = inline(dp);
            dp2 = symbderiv(dp,'x');
            d2 = inline(dp2);
            
            app.PrimeraderivadaEditField.Value = string(dp);
            app.SegundaderivadaEditField.Value= string(dp2);
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            if tol <= 0
                msgbox(sprintf("Ingrese una tolerancia valida."));
            else
                if iter <=0
                    msgbox(sprintf("Ingrese un numero de iteraciones valido."));
                else
                    contador = 1;
                    error = tol + 1;
                    y0 = f(x0);
                    d0 = d(x0);
                    dd0 = d2(x0);
                    resultado = [[0,x0,y0,d0,dd0,"---"];];
            
                    while y0 ~= 0 && error > tol && d0 ~= 0 && contador < iter
                        x1 = x0 - ((y0*d0)/((d0^2)-(y0*dd0)));
                        error = er(x1,x0);
                        x0 = x1;
                        y0 = f(x1);
                        d0 = d(x1);
                        dd0 = d2(x1);
                        resultado = [resultado;{contador,num2str(x0,15),num2str(y0,15),num2str(d0,15),num2str(dd0,15),error};]; %#ok<*AGROW> 
                        contador = contador + 1;
                    end
            
                    if y0 == 0
                        msgbox(sprintf("%g es raiz.", x0));
                    else
                        if error < tol
                            msgbox(sprintf("%g es una raiz aproximada.", x0));
                        else
                            if d0 == 0
                                msgbox(sprintf("Problema con la derivada, puede haber raiz multiple."));
                            else
                                msgbox(sprintf("Fracaso en %g iteraciones.", iter));
                            end
                        end
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func, x0, tol, iter)
            app.FuncinEditField.Value = func;
            app.X0EditField.Value = x0;
            app.ToleranciaEditField.Value = tol;
            app.IteracionesEditField.Value = iter;
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una función."));
            else
                try
                    fplot(app.UIAxes,func);
                catch
                    msgbox("Error en la función, revísela y vuelva a intentar.");
                end
                x0 = app.X0EditField.Value;
                tol = app.ToleranciaEditField.Value;
                iter = app.IteracionesEditField.Value;
                err = app.ErrorSwitch.Value;
                        
                t = raicesmultiples_metodo(app,func,x0,tol,iter,err);
                app.UITable.Data = t;
            end
        end

        % Close request function: RaicesmultiplesUIFigure
        function RaicesmultiplesUIFigureCloseRequest(app, event)
            setappdata(0,'f',app.FuncinEditField.Value);
            setappdata(0,'x0',app.X0EditField.Value);
            setappdata(0,'t',app.ToleranciaEditField.Value);
            setappdata(0,'i',app.IteracionesEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda("Raices Múltiples");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RaicesmultiplesUIFigure = uifigure('Visible', 'off');
            app.RaicesmultiplesUIFigure.Name = 'Raices multiples';
            app.RaicesmultiplesUIFigure.CloseRequestFcn = createCallbackFcn(app, @RaicesmultiplesUIFigureCloseRequest, true);
            app.FuncinEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.PrimeraderivadaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.PrimeraderivadaEditFieldLabel.Text = 'Primera derivada';
            app.PrimeraderivadaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.PrimeraderivadaEditField.Editable = 'off';
            app.SegundaderivadaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.SegundaderivadaEditFieldLabel.Text = 'Segunda derivada';
            app.SegundaderivadaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'text');
            app.SegundaderivadaEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.RaicesmultiplesUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MTODODERAICESMLTIPLESLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.MTODODERAICESMLTIPLESLabel.Text = 'MÉTODO DE RAICES MÚLTIPLES';
            app.ToleranciaEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.RaicesmultiplesUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Absoluto', 'Relativo'};
            app.ErrorSwitch.Value = 'Absoluto';
            app.EjecutarButton = uibutton(app.RaicesmultiplesUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.UITable = uitable(app.RaicesmultiplesUIFigure);
            app.UITable.ColumnName = {'n'; 'xn'; 'f(xn)'; 'f''(xn)'; 'f''''(xn)'; 'error'};
            app.UITable.RowName = {};
            app.X0EditFieldLabel = uilabel(app.RaicesmultiplesUIFigure);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.RaicesmultiplesUIFigure, 'numeric');
            app.AyudaButton = uibutton(app.RaicesmultiplesUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.RaicesmultiplesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_raicesmultiples(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RaicesmultiplesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RaicesmultiplesUIFigure)
        end
    end
end