classdef App_reglafalsa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReglafalsaUIFigure         matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        XiEditFieldLabel           matlab.ui.control.Label
        XiEditField                matlab.ui.control.NumericEditField
        XsEditFieldLabel           matlab.ui.control.Label
        XsEditField                matlab.ui.control.NumericEditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        ErrorSwitchLabel           matlab.ui.control.Label
        ErrorSwitch                matlab.ui.control.ToggleSwitch
        EjecutarButton             matlab.ui.control.Button
        MTODOREGLAFALSALabel       matlab.ui.control.Label
        AyudaButton                matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function [resultado] = reglafalsa_metodo(app,func,a,b,tol,iter,err)
            resultado = [];
            f = inline(func);
            fa = f(a);
            fb = f(b);
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            if tol <= 0
                msgbox(sprintf('Ingrese una tolerancia válida'));
            elseif iter <= 0
                msgbox(sprintf('Ingrese un numero de iteraciones válida'));
            elseif fa == 0
                msgbox(sprintf('%g es una raiz', a));
            elseif fb == 0
                msgbox(sprintf('%g es una raiz', b));
            else
                i = 1;
                error = tol + 1;
                m = a - ((f(a)*(a-b))/(f(a)-f(b)));
                fm = f(m);
            
                resultado = [[i,a,b,m,fm,"---"];];
            
                while fm ~= 0 && error > tol && i < iter
                    if fa*fm < 0
                        b = m;
                        fb = fm;
                    else
                        a = m;
                        fa = fm;
                    end
                    aux = m;
                    m = a - ((f(a)*(b-a))/(f(b)-f(a)));
                    fm = f(m);
                    i = i + 1;
                    error = er(m,aux);
                    resultado = [resultado;{i,num2str(a,15),num2str(b,15),num2str(m,15),num2str(fm,15),error};];
                end
                
                if error < tol
                    msgbox(sprintf('%g es raiz aproximada',m));
                end
            
                if fm == 0
                    msgbox(sprintf('%g es raiz',m));
                end
            
                if error > tol
                    msgbox(sprintf('Fallo para conseguir tolerancia de %g en %g iteraciones.', tol,iter));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func, xi, xs, tol, iter)
            app.FuncinEditField.Value = func;
            app.XiEditField.Value = xi;
            app.XsEditField.Value = xs;
            app.ToleranciaEditField.Value = tol;
            app.IteracionesEditField.Value = iter;
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion."));
            else
                try
                    fplot(app.UIAxes,func);
                catch
                    msgbox("Error en la función, revísela y vuelva a intentar.");
                end
                xi = app.XiEditField.Value;
                xs = app.XsEditField.Value;
                tol= app.ToleranciaEditField.Value;
                iter = app.IteracionesEditField.Value;
                err = app.ErrorSwitch.Value;
                
                t = reglafalsa_metodo(app,func,xi,xs,tol,iter,err);
                app.UITable.Data = t;
            end
        end

        % Close request function: ReglafalsaUIFigure
        function ReglafalsaUIFigureCloseRequest(app, event)
            setappdata(0,'f',app.FuncinEditField.Value);
            setappdata(0,'x0',app.XiEditField.Value);
            setappdata(0,'x1',app.XsEditField.Value);
            setappdata(0,'t',app.ToleranciaEditField.Value);
            setappdata(0,'i',app.IteracionesEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda("Regla Falsa");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReglafalsaUIFigure = uifigure('Visible', 'off');
            app.ReglafalsaUIFigure.Name = 'Regla falsa';
            app.ReglafalsaUIFigure.CloseRequestFcn = createCallbackFcn(app, @ReglafalsaUIFigureCloseRequest, true);
            app.UITable = uitable(app.ReglafalsaUIFigure);
            app.UITable.ColumnName = {'n'; 'xi'; 'xs'; 'xm'; 'f(m)'; 'Error'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.ReglafalsaUIFigure);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FuncinEditFieldLabel = uilabel(app.ReglafalsaUIFigure);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.ReglafalsaUIFigure, 'text');
            app.XiEditFieldLabel = uilabel(app.ReglafalsaUIFigure);
            app.XiEditFieldLabel.Text = 'Xi';
            app.XiEditField = uieditfield(app.ReglafalsaUIFigure, 'numeric');
            app.XsEditFieldLabel = uilabel(app.ReglafalsaUIFigure);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.ReglafalsaUIFigure, 'numeric');
            app.ToleranciaEditFieldLabel = uilabel(app.ReglafalsaUIFigure);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.ReglafalsaUIFigure, 'numeric');
            app.IteracionesEditFieldLabel = uilabel(app.ReglafalsaUIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.ReglafalsaUIFigure, 'numeric');
            app.ErrorSwitchLabel = uilabel(app.ReglafalsaUIFigure);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.ReglafalsaUIFigure, 'toggle');
            app.ErrorSwitch.Items = {'Relativo', 'Absoluto'};
            app.ErrorSwitch.Value = 'Relativo';
            app.EjecutarButton = uibutton(app.ReglafalsaUIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.MTODOREGLAFALSALabel = uilabel(app.ReglafalsaUIFigure);
            app.MTODOREGLAFALSALabel.Text = 'MÉTODO REGLA FALSA';
            app.AyudaButton = uibutton(app.ReglafalsaUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.ReglafalsaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_reglafalsa(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReglafalsaUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReglafalsaUIFigure)
        end
    end
end