classdef App_secante < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SecanteUI                  matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        X0EditFieldLabel           matlab.ui.control.Label
        X0EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        FuncinEditFieldLabel       matlab.ui.control.Label
        FuncinEditField            matlab.ui.control.EditField
        ToleranciaEditFieldLabel   matlab.ui.control.Label
        ToleranciaEditField        matlab.ui.control.NumericEditField
        UIAxes                     matlab.ui.control.UIAxes
        ErrorSwitchLabel           matlab.ui.control.Label
        ErrorSwitch                matlab.ui.control.ToggleSwitch
        MTODOSECANTELabel          matlab.ui.control.Label
        EjecutarButton             matlab.ui.control.Button
        IteracionesEditFieldLabel  matlab.ui.control.Label
        IteracionesEditField       matlab.ui.control.NumericEditField
        AyudaButton                matlab.ui.control.Button
    end


    methods (Access = private)
    
        function updateTable(app)
            t = app.UITable.DisplayData;  
        end
        
    end
    
    methods (Access = public)
        
        function [resultado] = secante_metodo(app,func,x0,x1,tol,iter,err)
            resultado = [];
            f = inline(func);
            
            errf = "abs(a-b)";
            if err == "Relativo"
                errf = "abs((a-b)/a)";
            end
            er = inline(errf);
            
            y0 = f(x0);
            y1 = f(x1);
            cont = 2;
            error = tol + 1;
            resultado = [[0,x0,y0,"---"];[1,x1,y1,"---"]];
            if y0 == 0
                msgbox(sprintf('%g es una raiz',x0));
            else
                if y1 == 0
                    msgbox(sprintf('%g es una raiz',x1));
                else
                    if tol <= 0
                        msgbox(sprintf('Ingrese una tolerancia valida'));
                    else
                        if iter <= 2
                            msgbox(sprintf('Ingrese un numero de iteraciones valido'));
                        else
            
                            while y0 ~= 0 && error > tol && cont < iter
                                xn = x1-((y1*(x1-x0))/(y1-y0));
                                yn = f(xn);
                                error = er(xn,x1);
                                resultado = [resultado; {cont,num2str(xn,15),num2str(yn,15),error}];
                                y0 = y1;
                                x0 = x1;
                                y1 = yn;
                                x1 = xn;
                                cont = cont + 1;
                            end
            
                            if y1 == 0 
                                msgbox(sprintf('%g es una raiz.', x1));
                            elseif error < tol
                                msgbox(sprintf('%g es una raiz aproximada.',x1));
                            else
                                msgbox(sprintf('Fracaso en %g iteraciones.',iter));
                            end
                        end
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, func, x0, x1, tol, iter)
            t = [[0,0,0,0];];

            app.UITable.Data = t;
            app.FuncinEditField.Value = func;
            app.X0EditField.Value = x0;
            app.X1EditField.Value = x1;
            app.ToleranciaEditField.Value = tol;
            app.IteracionesEditField.Value = iter;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            func = app.FuncinEditField.Value;
            if func == ""
                msgbox(sprintf("Ingrese una funcion."));
            else
                fplot(app.UIAxes,func);
                x0 = app.X0EditField.Value;
                x1 = app.X1EditField.Value;
                tol= app.ToleranciaEditField.Value;
                iter = app.IteracionesEditField.Value;
                err = app.ErrorSwitch.Value;
                
                t = secante_metodo(app,func,x0,x1,tol,iter,err);
                app.UITable.Data = t;
                app.updateTable();
            end
        end

        % Close request function: SecanteUI
        function SecanteUICloseRequest(app, event)
            setappdata(0,'f',app.FuncinEditField.Value);
            setappdata(0,'x0',app.X0EditField.Value);
            setappdata(0,'x1',app.X1EditField.Value);
            setappdata(0,'t',app.ToleranciaEditField.Value);
            setappdata(0,'i',app.IteracionesEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda("Secante");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SecanteUI = uifigure('Visible', 'off');
            app.SecanteUI.Name = 'Secante';
            app.SecanteUI.CloseRequestFcn = createCallbackFcn(app, @SecanteUICloseRequest, true);
            app.UITable = uitable(app.SecanteUI);
            app.UITable.ColumnName = {'n'; 'xn'; 'f(xn)'; 'Error'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.ColumnEditable = [true false true true];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.X0EditFieldLabel = uilabel(app.SecanteUI);
            app.X0EditFieldLabel.Text = 'X0';
            app.X0EditField = uieditfield(app.SecanteUI, 'numeric');
            app.X1EditFieldLabel = uilabel(app.SecanteUI);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.SecanteUI, 'numeric');
            app.FuncinEditFieldLabel = uilabel(app.SecanteUI);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncinEditField = uieditfield(app.SecanteUI, 'text');
            app.ToleranciaEditFieldLabel = uilabel(app.SecanteUI);
            app.ToleranciaEditFieldLabel.Text = 'Tolerancia';
            app.ToleranciaEditField = uieditfield(app.SecanteUI, 'numeric');
            app.UIAxes = uiaxes(app.SecanteUI);
            title(app.UIAxes, 'f(x)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ErrorSwitchLabel = uilabel(app.SecanteUI);
            app.ErrorSwitchLabel.Text = 'Error';
            app.ErrorSwitch = uiswitch(app.SecanteUI, 'toggle');
            app.ErrorSwitch.Items = {'Relativo', 'Absoluto'};
            app.ErrorSwitch.Value = 'Relativo';
            app.MTODOSECANTELabel = uilabel(app.SecanteUI);
            app.MTODOSECANTELabel.Text = 'MÉTODO SECANTE';
            app.EjecutarButton = uibutton(app.SecanteUI, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Tooltip = {'Correr método'};
            app.EjecutarButton.Text = 'Ejecutar';
            app.IteracionesEditFieldLabel = uilabel(app.SecanteUI);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.IteracionesEditField = uieditfield(app.SecanteUI, 'numeric');
            app.AyudaButton = uibutton(app.SecanteUI, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.SecanteUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_secante(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SecanteUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SecanteUI)
        end
    end
end