classdef App_sistemas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SistemasdeecuacionesUIFigure  matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        GausssimpleButton             matlab.ui.control.Button
        PivoteoparcialButton          matlab.ui.control.Button
        PivoteototalButton            matlab.ui.control.Button
        AyudaButton                   matlab.ui.control.Button
        VariablesEditFieldLabel       matlab.ui.control.Label
        VariablesEditField            matlab.ui.control.NumericEditField
        ActualizarButton              matlab.ui.control.Button
        UITable2                      matlab.ui.control.Table
        ALabel                        matlab.ui.control.Label
        BLabel                        matlab.ui.control.Label
        UITable3                      matlab.ui.control.Table
        XLabel                        matlab.ui.control.Label
        DeshacerButton                matlab.ui.control.Button
        VolverButton                  matlab.ui.control.Button
    end

    properties (Access = private)
        backupA = zeros(4,4);
        backupB = zeros(4,1);
    end
    
    methods (Access = public)
        
        function tamano_tabla(app)
            tam = app.VariablesEditField.Value;
            t = zeros(tam,tam);
            t2 = zeros(tam,1);
            app.UITable.Data = t;
            app.UITable2.Data = t2;
        end
        
        function guardarTablas(app)
            app.backupA = app.UITable.Data;
            app.backupB = app.UITable2.Data;
        end
        
        
        function cargarTabla(app)
            app.UITable.Data = app.backupA;
            app.UITable2.Data = app.backupB;
        end
    end
    
    methods (Access = private)
        
        function results = Gauss_simple(app)
            MatA = app.UITable.Data;
            MatB = app.UITable2.Data;
            Mat = [MatA MatB];
            n = app.VariablesEditField.Value;
            indices = 1:n;
            
            for k = 1:(n-1)
                for i = (k+1):n
                    m = Mat(i,k)/Mat(k,k);
                    for j = k:(n+1)
                        Mat(i,j) = Mat(i,j)-m*Mat(k,j);
                    end
                end
            end
            A = Mat(:,1:end-1);
            app.UITable.Data = A;
            b = Mat(:,end:end);
            app.UITable2.Data = b;
            results = app.Sust_regresiva(A,b,n);
            app.UITable3.Data = [indices' results];
        end
        
        function x = Sust_regresiva(app,A,b,n)
            x = zeros(n,1);
            x(n) = b(n)/A(n,n);               
            for i = n-1:-1:1                    
                sum = 0;
                for j = n:-1:i+1                
                    sum = sum + A(i,j)*x(j);    
                end 
                x(i) = (b(i)- sum)/A(i,i);
            end 
            app.UITable3.Data = x;
        end
        
        function results = Gauss_pivoteo(app,pivoteo)
            MatA = app.UITable.Data;
            MatB = app.UITable2.Data;
            Mat = [MatA MatB];
            n = app.VariablesEditField.Value;
            indices = 1 : n;
            
            for k = 1:(n-1)
                if pivoteo == 1
                    [Mat,indices] = app.pivoteoTotal(Mat,n,k,indices);
                else
                    Mat = app.pivoteoParcial(Mat,n,k);
                end
                
                for i = k+1:n
                    m = Mat(i,k)/Mat(k,k);
                    for j = k:n+1
                        Mat(i,j) = Mat(i,j)-m*Mat(k,j);
                    end
                end
            end
            A = Mat(:,1:end-1);
            app.UITable.Data = A;
            b = Mat(:,end:end);
            app.UITable2.Data = b;
            results = app.Sust_regresiva(A,b,n);
            app.UITable3.Data = [indices' results];
        end
        
        function Mat = pivoteoParcial(app,Mat,n,k)
            pivote = abs(Mat(k,k));
            filapivote = k;
            for i = k+1:n
                aux = abs(Mat(i,k));
                if pivote < aux
                    pivote = aux;
                    filapivote = i;
                end
            end
            if pivote == 0
                msgbox(sprintf("No tiene solución única."));
            else
                if filapivote ~= k
                    Mat = app.swapRow(Mat,filapivote,k);
                end
            end
        end
        
        function [Ab,indicesX] = pivoteoTotal(app,Ab,n,k,indices)
            pivote = 0;
            filaPivote = k;
            columnaPivote = k;
            indicesX = indices;
            for i = k:n
                for j = k:n
                    aux = abs(Ab(i,j));
                    if pivote < aux
                        pivote = aux;
                        filaPivote = i;
                        columnaPivote = j;
                    end
                end
            end
            if pivote == 0
                msgbox(sprintf("No tiene solución única."));
            else
                if filaPivote ~= k
                    Ab = app.swapRow(Ab,filaPivote,k);
                end
                if columnaPivote ~= k
                    Ab = app.swapCol(Ab,columnaPivote,k);
                    indicesX = app.swapIndex(indicesX,columnaPivote,k);
                end
            end 
        end
        
        function A = swapRow(~,A, row1, row2)
          A([row2, row1], :) = A([row1, row2], :);
        end
        
        function A = swapCol(~,A, row1, row2)
           t = A(:,row1);
           A(:,row1) = A(:,row2);
           A(:,row2) = t;
        end
        
        function A = swapIndex(~,A, i, j)
            t = A(i);
            A(i) = A(j);
            A(j) = t;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tamano_tabla(app)
        end

        % Button pushed function: GausssimpleButton
        function GausssimpleButtonPushed(app, event)
           app.guardarTablas();
           app.Gauss_simple();
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            tamano_tabla(app)
        end

        % Button pushed function: PivoteoparcialButton
        function PivoteoparcialButtonPushed(app, event)
            app.guardarTablas();
            app.Gauss_pivoteo(2);
        end

        % Button pushed function: PivoteototalButton
        function PivoteototalButtonPushed(app, event)
            app.guardarTablas();
            app.Gauss_pivoteo(1)
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
            app.cargarTabla();
        end

        % Close request function: SistemasdeecuacionesUIFigure
        function SistemasdeecuacionesUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            App_Main();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SistemasdeecuacionesUIFigure = uifigure('Visible', 'off');
            app.SistemasdeecuacionesUIFigure.Name = 'Sistemas de ecuaciones';
            app.SistemasdeecuacionesUIFigure.CloseRequestFcn = createCallbackFcn(app, @SistemasdeecuacionesUIFigureCloseRequest, true);
            app.UITable = uitable(app.SistemasdeecuacionesUIFigure);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.GausssimpleButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.GausssimpleButton.ButtonPushedFcn = createCallbackFcn(app, @GausssimpleButtonPushed, true);
            app.GausssimpleButton.Text = 'Gauss simple';
            app.PivoteoparcialButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.PivoteoparcialButton.ButtonPushedFcn = createCallbackFcn(app, @PivoteoparcialButtonPushed, true);
            app.PivoteoparcialButton.Text = 'Pivoteo parcial';
            app.PivoteototalButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.PivoteototalButton.ButtonPushedFcn = createCallbackFcn(app, @PivoteototalButtonPushed, true);
            app.PivoteototalButton.Text = 'Pivoteo total';
            app.AyudaButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.AyudaButton.Text = 'Ayuda';
            app.VariablesEditFieldLabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.VariablesEditFieldLabel.Text = 'Variables';
            app.VariablesEditField = uieditfield(app.SistemasdeecuacionesUIFigure, 'numeric');
            app.VariablesEditField.Value = 4;
            app.ActualizarButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.UITable2 = uitable(app.SistemasdeecuacionesUIFigure);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.ALabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.BLabel.Text = 'B';
            app.UITable3 = uitable(app.SistemasdeecuacionesUIFigure);
            app.UITable3.ColumnName = {'Indice'; 'Valor'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = [true false];
            app.XLabel = uilabel(app.SistemasdeecuacionesUIFigure);
            app.XLabel.Text = 'X';
            app.DeshacerButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer';
            app.VolverButton = uibutton(app.SistemasdeecuacionesUIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            % Show the figure after all components are created
            app.SistemasdeecuacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_sistemas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SistemasdeecuacionesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SistemasdeecuacionesUIFigure)
        end
    end
end