classdef App_splines < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrazadoresUIFigure       matlab.ui.Figure
        UITableA                 matlab.ui.control.Table
        NPuntosEditFieldLabel    matlab.ui.control.Label
        NPuntosEditField         matlab.ui.control.NumericEditField
        ActualizarButton         matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        UITable                  matlab.ui.control.Table
        AyudaButton              matlab.ui.control.Button
        TipoSplineButtonGroup    matlab.ui.container.ButtonGroup
        LinealButton             matlab.ui.control.RadioButton
        CuadrticoButton          matlab.ui.control.RadioButton
        CbicoButton              matlab.ui.control.RadioButton
        EcuacionesLabel          matlab.ui.control.Label
        MtodosdeTrazadoresLabel  matlab.ui.control.Label
        ResolverButton           matlab.ui.control.Button
        AcumularCheckBox         matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        
        function tamano_tabla(app)
            tam = app.NPuntosEditField.Value;
            t = zeros(tam,2);
            app.UITableA.Data = t;
        end
        
        function funciones = splines(app,x,y,n,grado)
            format long g
            switch grado
                case 2
                    [A,b,~] = app.splinesCuadratico(x,y,n);
                case 3
                    [A,b,~] = app.splinesCubico(x,y,n);
                otherwise
                    [A,b,~] = app.splinesLineal(x,y,n);
            end
            sol = linsolve(A,b);
            for i = 1:(n-1)
                f = 0;
                for j = 1:(grado+1)
                    gradoTerm = grado+1-j;
                    coeficiente = sol((grado+1)*(i-1)+j);
                    coeficiente(abs(coeficiente)<1e-5)=0;
                    if gradoTerm ~= 0 && coeficiente ~= 0
        			    f = f +strjoin(["+"  num2str(coeficiente) "*x^" num2str(gradoTerm)],"");
                    elseif coeficiente ~= 0
                        f = f +strjoin(["+"  num2str(coeficiente)],"");
                    end
                end
                funciones(i) = f; %#ok<*AGROW> 
            end 
        end
        
        function [A,b,dim] = splinesLineal(~,x,y,n)
        	A = zeros(2*(n-1),2*(n-1));
        	b = zeros(2*(n-1),1);
            for i = 1:(n-1)
        		A(2*i-1,2*i-1) = x(i);
        		A(2*i-1,2*i) = 1;
        		b(2*i-1) = y(i);
        		A(2*i,2*i-1) = x(i+1);
        		A(2*i,2*i) = 1;
        		b(2*i) = y(i+1);
            end
            dim = 2*(n-1);
        end
        
        function [A,b,dim] = splinesCuadratico(~,x,y,n)
        	A = zeros(3*(n-1),3*(n-1));
        	b = zeros(3*(n-1),1);
            for i = 1:(n-1)
        		A(2*i-1,3*i-2) = x(i)^2;
        		A(2*i-1,3*i-1) = x(i);
        		A(2*i-1,3*i) = 1;
        		b(2*i-1) = y(i);
        		A(2*i,3*i-2) = x(i+1)^2;
        		A(2*i,3*i-1) = x(i+1);
        		A(2*i,3*i) = 1;
        		b(2*i) = y(i+1);
            end
            for i = 1:(n-2)
        		A(2*n-2+i,3*i-2) = 2*x(i+1);
        		A(2*n-2+i,3*i-1) = 1;
        		A(2*n-2+i,3*i+1) = -2*x(i+1);
        		A(2*n-2+i,3*i+2) = -1;
        		b(2*n-2+i) = 0;
            end
        	A(3*n-3,1) = 2;
        	b(3*n-3) = 0;
            dim = 3*(n-1);
        end
        
        function [A,b,dim] = splinesCubico (~,x,y,n)
        	A = zeros(4*(n-1),4*(n-1));
        	b = zeros(4*(n-1),1);
            for i = 1:(n-1)
        		A(2*i-1,4*i-3) = x(i)^3;
        		A(2*i-1,4*i-2) = x(i)^2;
        		A(2*i-1,4*i-1) = x(i);
        		A(2*i-1,4*i) = 1;
        		b(2*i-1) = y(i);
        		A(2*i,4*i-3) = x(i+1)^3;
        		A(2*i,4*i-2) = x(i+1)^2;
        		A(2*i,4*i-1) = x(i+1);
        		A(2*i,4*i) = 1;
        		b(2*i) = y(i+1);
            end
            for i = 1:(n-2)
        		A(2*n-2+i,4*i-3) = 3*x(i+1)^2;
        		A(2*n-2+i,4*i-2) = 2*x(i+1);
        		A(2*n-2+i,4*i-1) = 1;
        		A(2*n-2+i,4*i+1) = -3*x(i+1)^2;
        		A(2*n-2+i,4*i+2) = -2*x(i+1);
        		A(2*n-2+i,4*i+3) = -1;
        		b(2*n-2+i) = 0;
            end
            for i = 1:(n-2)
        		A(3*n-4+i,4*i-3) = 6*x(i+1);
        		A(3*n-4+i,4*i-2) = 2;
        		A(3*n-4+i,4*i+1) = -6*x(i+1);
        		A(3*n-4+i,4*i+2) = -2;
        		b(3*n-4+i) = 0;
            end
        	A(4*n-5,1) = 6*x(1);
        	A(4*n-5,2) = 2;
        	A(4*n-4,4*n-7) = 6*x(n);
        	A(4*n-4,4*n-6) = 2;
        	b(4*n-5) = 0;
        	b(4*n-4) = 0;
            dim = 4*(n-1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, puntos, numPuntos)
            app.UITableA.Data = puntos;
            app.NPuntosEditField.Value = numPuntos;
        end

        % Button pushed function: ActualizarButton
        function ActualizarButtonPushed(app, event)
            app.tamano_tabla();
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            x = app.UITableA.Data(:,1)';
            y = app.UITableA.Data(:,end)';
            n = app.NPuntosEditField.Value;
            if length(x) == length(unique(x))
                tipo = app.TipoSplineButtonGroup.get('SelectedObject').Text;
                switch tipo
                    case 'Lineal'
                        funciones = app.splines(x,y,n,1);
                    case 'Cuadrático'
                        funciones = app.splines(x,y,n,2);
                    case 'Cúbico'
                        funciones = app.splines(x,y,n,3);
                end
                
                if app.AcumularCheckBox.Value == 1
                    hold(app.UIAxes,'on');
                else
                    hold(app.UIAxes,'off');
                end
                plot(app.UIAxes,x, y, 'o');
                datos = [];
                hold(app.UIAxes, 'on');
                for i = 1:n-1
                    ply = funciones(i);
                    fplot(app.UIAxes,ply,[x(i) x(i+1)]);
                    rangostr = num2str(x(i))+", "+num2str(x(i+1));
                    datos = [datos; [ply rangostr]];
                end
                app.UITable.Data = datos;
            else
                msgbox("Existen dos puntos con un mismo valor de x.");
            end
        end

        % Close request function: TrazadoresUIFigure
        function TrazadoresUIFigureCloseRequest(app, event)
            setappdata(0,'puntos',app.UITableA.Data);
            setappdata(0,'numPuntos',app.NPuntosEditField.Value);
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda_interpolacion("Trazadores");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrazadoresUIFigure = uifigure('Visible', 'off');
            app.TrazadoresUIFigure.Name = 'Trazadores';
            app.TrazadoresUIFigure.CloseRequestFcn = createCallbackFcn(app, @TrazadoresUIFigureCloseRequest, true);
            app.UITableA = uitable(app.TrazadoresUIFigure);
            app.UITableA.ColumnName = {'X'; 'Y'};
            app.UITableA.RowName = {};
            app.UITableA.ColumnEditable = true;
            app.NPuntosEditFieldLabel = uilabel(app.TrazadoresUIFigure);
            app.NPuntosEditFieldLabel.Text = 'N. Puntos';
            app.NPuntosEditField = uieditfield(app.TrazadoresUIFigure, 'numeric');
            app.NPuntosEditField.Value = 4;
            app.ActualizarButton = uibutton(app.TrazadoresUIFigure, 'push');
            app.ActualizarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarButtonPushed, true);
            app.ActualizarButton.Text = 'Actualizar';
            app.UIAxes = uiaxes(app.TrazadoresUIFigure);
            title(app.UIAxes, 'Resultados')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.TrazadoresUIFigure);
            app.UITable.ColumnName = {'Funcion'; 'Intervalo'};
            app.UITable.RowName = {};
            app.AyudaButton = uibutton(app.TrazadoresUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.TipoSplineButtonGroup = uibuttongroup(app.TrazadoresUIFigure);
            app.TipoSplineButtonGroup.Title = 'Tipo Spline';
            app.LinealButton = uiradiobutton(app.TipoSplineButtonGroup);
            app.LinealButton.Text = 'Lineal';
            app.LinealButton.Value = true;
            app.CuadrticoButton = uiradiobutton(app.TipoSplineButtonGroup);
            app.CuadrticoButton.Text = 'Cuadrático';
            app.CbicoButton = uiradiobutton(app.TipoSplineButtonGroup);
            app.CbicoButton.Text = 'Cúbico';
            app.EcuacionesLabel = uilabel(app.TrazadoresUIFigure);
            app.EcuacionesLabel.Text = 'Ecuaciones';
            app.MtodosdeTrazadoresLabel = uilabel(app.TrazadoresUIFigure);
            app.MtodosdeTrazadoresLabel.Text = 'Métodos de Trazadores';
            app.ResolverButton = uibutton(app.TrazadoresUIFigure, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.AcumularCheckBox = uicheckbox(app.TrazadoresUIFigure);
            app.AcumularCheckBox.Text = 'Acumular';
            % Show the figure after all components are created
            app.TrazadoresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_splines(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrazadoresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrazadoresUIFigure)
        end
    end
end