classdef App_unavar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolucindeecuacionesUIFigure   matlab.ui.Figure
        BusquedaincrementalButton     matlab.ui.control.Button
        SecanteButton                 matlab.ui.control.Button
        NewtonButton                  matlab.ui.control.Button
        ReglaFalsaButton              matlab.ui.control.Button
        BiseccinButton                matlab.ui.control.Button
        PuntoFijoButton               matlab.ui.control.Button
        RaicesMultiplesButton         matlab.ui.control.Button
        AnlisisNumricoLabel           matlab.ui.control.Label
        EcuacionesdeunavariableLabel  matlab.ui.control.Label
        MtodosabiertosLabel           matlab.ui.control.Label
        MtodoscerradosLabel           matlab.ui.control.Label
        VolverButton                  matlab.ui.control.Button
        AyudaButton                   matlab.ui.control.Button
        GraficadorButton              matlab.ui.control.Button
    end

    properties (Access = public)
        funcion = " "; % Description
        x0 = 0.0;
        x1 = 0.0;
        tol = 0.0;
        iter = 0.0;
    end
    
    methods (Access = public)
        
        function update_values(app)
            app.funcion = getappdata(0,'f');
            app.x0 = getappdata(0,'x0');
            app.x1 = getappdata(0,'x1');
            app.tol = getappdata(0,'t');
            app.iter = getappdata(0,'i');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BusquedaincrementalButton
        function BusquedaincrementalButtonPushed(app, event)
            update_values(app);
            App_incremental(app.funcion, app.x0, app.iter);
        end

        % Button pushed function: SecanteButton
        function SecanteButtonPushed(app, event)
            update_values(app);
            App_secante(app.funcion, app.x0, app.x1, app.tol, app.iter);
        end

        % Button pushed function: NewtonButton
        function NewtonButtonPushed(app, event)
            update_values(app);
            App_newton(app.funcion, app.x0, app.tol, app.iter);
        end

        % Button pushed function: ReglaFalsaButton
        function ReglaFalsaButtonPushed(app, event)
            update_values(app);
            App_reglafalsa(app.funcion, app.x0, app.x1, app.tol, app.iter);
        end

        % Button pushed function: BiseccinButton
        function BiseccinButtonPushed(app, event)
            update_values(app);
            App_biseccion(app.funcion, app.x0, app.x1, app.tol, app.iter);
        end

        % Button pushed function: PuntoFijoButton
        function PuntoFijoButtonPushed(app, event)
            update_values(app);
            App_puntofijo(app.funcion, app.x0, app.tol, app.iter);
        end

        % Button pushed function: RaicesMultiplesButton
        function RaicesMultiplesButtonPushed(app, event)
            update_values(app);
            App_raicesmultiples(app.funcion, app.x0, app.tol, app.iter);
        end

        % Close request function: SolucindeecuacionesUIFigure
        function SolucindeecuacionesUIFigureCloseRequest(app, event)
           delete(app);
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            App_Main();
            delete(app);
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            App_ayuda("");
        end

        % Button pushed function: GraficadorButton
        function GraficadorButtonPushed(app, event)
            update_values(app);
            App_grapher(app.funcion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolucindeecuacionesUIFigure = uifigure('Visible', 'off');
            app.SolucindeecuacionesUIFigure.Name = 'Solución de ecuaciones';
            app.SolucindeecuacionesUIFigure.CloseRequestFcn = createCallbackFcn(app, @SolucindeecuacionesUIFigureCloseRequest, true);
            app.BusquedaincrementalButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.BusquedaincrementalButton.ButtonPushedFcn = createCallbackFcn(app, @BusquedaincrementalButtonPushed, true);
            app.BusquedaincrementalButton.Text = 'Busqueda incremental';
            app.SecanteButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.SecanteButton.ButtonPushedFcn = createCallbackFcn(app, @SecanteButtonPushed, true);
            app.SecanteButton.Text = 'Secante';
            app.NewtonButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.NewtonButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonButtonPushed, true);
            app.NewtonButton.Text = 'Newton';
            app.ReglaFalsaButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.ReglaFalsaButton.ButtonPushedFcn = createCallbackFcn(app, @ReglaFalsaButtonPushed, true);
            app.ReglaFalsaButton.Text = 'Regla Falsa';
            app.BiseccinButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.BiseccinButton.ButtonPushedFcn = createCallbackFcn(app, @BiseccinButtonPushed, true);
            app.BiseccinButton.Text = 'Bisección';
            app.PuntoFijoButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.PuntoFijoButton.ButtonPushedFcn = createCallbackFcn(app, @PuntoFijoButtonPushed, true);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.RaicesMultiplesButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.RaicesMultiplesButton.ButtonPushedFcn = createCallbackFcn(app, @RaicesMultiplesButtonPushed, true);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.AnlisisNumricoLabel = uilabel(app.SolucindeecuacionesUIFigure);
            app.AnlisisNumricoLabel.Text = 'Análisis Numérico';
            app.EcuacionesdeunavariableLabel = uilabel(app.SolucindeecuacionesUIFigure);
            app.EcuacionesdeunavariableLabel.Text = 'Ecuaciones de una variable';
            app.MtodosabiertosLabel = uilabel(app.SolucindeecuacionesUIFigure);
            app.MtodosabiertosLabel.Text = 'Métodos abiertos';
            app.MtodoscerradosLabel = uilabel(app.SolucindeecuacionesUIFigure);
            app.MtodoscerradosLabel.Text = 'Métodos cerrados';
            app.VolverButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = 'Volver';
            app.AyudaButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            app.GraficadorButton = uibutton(app.SolucindeecuacionesUIFigure, 'push');
            app.GraficadorButton.ButtonPushedFcn = createCallbackFcn(app, @GraficadorButtonPushed, true);
            app.GraficadorButton.Text = 'Graficador';
            % Show the figure after all components are created
            app.SolucindeecuacionesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_unavar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolucindeecuacionesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolucindeecuacionesUIFigure)
        end
    end
end